classdef Juanita_w_pump_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TimestepsecPanel         matlab.ui.container.Panel
        LowEditField             matlab.ui.control.NumericEditField
        LowEditFieldLabel        matlab.ui.control.Label
        HighEditField            matlab.ui.control.NumericEditField
        HighEditFieldLabel       matlab.ui.control.Label
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        AutomatedoperationPanel  matlab.ui.container.Panel
        StateindicatorButton     matlab.ui.control.StateButton
        StartfromDropDown        matlab.ui.control.DropDown
        StartfromDropDownLabel   matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ManualoperationPanel     matlab.ui.container.Panel
        StopButton_2             matlab.ui.control.Button
        LowButton                matlab.ui.control.Button
        HighButton               matlab.ui.control.Button
        UIAxes2_2                matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function updatePlot(app, state)
            global t0 x y counter;

            if isempty(t0)
                t0 = tic;
            end
        
            % Add current time and state if state changes or it's the first point
            currentTime = toc(t0);
            if isempty(x) || isempty(y) || state ~= y(end)
                x = [x currentTime];
                y = [y state];
                counter = counter + 1;
            end
        
            % Ensure lengths are the same
            if length(x) ~= length(y)
                warning('Lengths of x and y are not synchronized. Adjusting...');
                minLength = min(length(x), length(y));
                x = x(1:minLength);
                y = y(1:minLength);
            end
        
            % Prepare step-like data for plotting
            if counter > 1
                % Duplicate each time and state value to create steps
                xStep = reshape([x; x], 1, []); % Repeat each x value
                yStep = reshape([y; y], 1, []); % Repeat each y value
                xStep(1) = []; % Remove the first duplicate
                yStep(end) = []; % Remove the last duplicate
            else
                % For the first point, just plot the single point
                xStep = x;
                yStep = y;
            end
        
            % Limit the plot to the last 30 seconds
            maxTimeWindow = 30; % Seconds
            validIndices = xStep >= max(0, currentTime - maxTimeWindow);
            xStep = xStep(validIndices);
            yStep = yStep(validIndices);
        
            % Plot the step-like data
            plot(app.UIAxes, xStep, yStep, '-r', 'LineWidth', 2);
            axis(app.UIAxes, [max(0, currentTime - maxTimeWindow), currentTime, -0.1, 1.1]);
        
            % Update x-axis ticks and labels
            app.UIAxes.XTick = linspace(max(0, currentTime - maxTimeWindow), currentTime, 4);
            app.UIAxes.XTickLabel = arrayfun(@(v) num2str(round(v, 1)), ...
                                             linspace(max(0, currentTime - maxTimeWindow), currentTime, 4), ...
                                             'UniformOutput', false);
                     

            end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        global t0 counter x y;
        clear;
                
        t0 = [];
        x = [];
        y = [];
        counter = 0;
        
%         I = imshow('Logo.png','parent',app.UIAxes2);
%         J = imshow('Lab_logo.png','AlphaData','parent',app.UIAxes2_2);
%         J.Visible = "off";
        end

        % Button pushed function: HighButton
        function HighButtonPushed(app, event)
            global a loopFlag t0 counter x y;
            
        % Check if Arduino is connected
            if isempty(a) || ~isvalid(a)
                disp('Arduino is not connected. Please click "Connect" first.');
                return;
            end
        
            % Set digital pins
            try
                writeDigitalPin(a, 'D3', 1);
                writeDigitalPin(a, 'D4', 1);
                disp('High signal sent to Arduino.');
            catch ME
                disp('Error writing to Arduino:');
                disp(ME.message);
            end
            
            loopFlag = true;
            if isempty(t0) == 1
                t0 = tic;
            end
            while true
                pause(0.3);
                if (loopFlag == false)
                    break;
                end
                x = [x toc(t0)];
                y = [y 1];
                counter = counter + 1;
                if counter > 30
                    % Normal operation for plotting the last 30 points
                    plot(app.UIAxes, x(counter-30:counter), y(counter-30:counter), '-r', 'LineWidth', 2);
                    axis(app.UIAxes, [x(counter-30) x(counter) -0.1 1.1]);
                    app.UIAxes.XTick = [x(counter-30) x(counter-20) x(counter-10) x(counter)];
                    app.UIAxes.XTickLabel = {num2str(round(x(counter-30), 1)), ...
                                             num2str(round(x(counter-20), 1)), ...
                                             num2str(round(x(counter-10), 1)), ...
                                             num2str(round(x(counter), 1))};
               else
                    % Fallback for fewer than 30 points
                    plot(app.UIAxes, x, y, '-r', 'LineWidth', 2);
                    axis(app.UIAxes, [0 max(x, [], 'omitnan') -0.1 1.1]);
                    app.UIAxes.XTick = linspace(0, max(x, [], 'omitnan'), 4);  % Adjust ticks dynamically
                    app.UIAxes.XTickLabel = arrayfun(@(v) num2str(round(v, 1)), ...
                                                     linspace(0, max(x, [], 'omitnan'), 4), ...
                                                     'UniformOutput', false);
              end

            end
        end

        % Button pushed function: LowButton
        function LowButtonPushed(app, event)
            global a loopFlag t0 counter x y;

            % Check if Arduino is connected
            if isempty(a) || ~isvalid(a)
                disp('Arduino is not connected. Please click "Connect" first.');
                return;
            end
        
            % Set digital pins to Low
            try
                writeDigitalPin(a, 'D3', 0);
                writeDigitalPin(a, 'D4', 0);
                disp('Low signal sent to Arduino.');
            catch ME
                disp('Error writing to Arduino:');
                disp(ME.message);
            end
        
            loopFlag = true;
            if isempty(t0)
                t0 = tic;
            end
            while true
                pause(0.3);
                if ~loopFlag
                    break;
                end
                x = [x toc(t0)];
                y = [y 0];
                counter = counter + 1;
                if counter > 30
                    % Normal operation for plotting the last 30 points
                    plot(app.UIAxes, x(counter-30:counter), y(counter-30:counter), '-r', 'LineWidth', 2);
                    axis(app.UIAxes, [x(counter-30) x(counter) -0.1 1.1]);
                    app.UIAxes.XTick = [x(counter-30) x(counter-20) x(counter-10) x(counter)];
                    app.UIAxes.XTickLabel = {num2str(round(x(counter-30), 1)), ...
                                             num2str(round(x(counter-20), 1)), ...
                                             num2str(round(x(counter-10), 1)), ...
                                             num2str(round(x(counter), 1))};
                else
                    % Fallback for fewer than 30 points
                    plot(app.UIAxes, x, y, '-r', 'LineWidth', 2);
                    axis(app.UIAxes, [0 max(x, [], 'omitnan') -0.1 1.1]);
                    app.UIAxes.XTick = linspace(0, max(x, [], 'omitnan'), 4);
                    app.UIAxes.XTickLabel = arrayfun(@(v) num2str(round(v, 1)), ...
                                                     linspace(0, max(x, [], 'omitnan'), 4), ...
                                                     'UniformOutput', false);
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global a loopFlag step_t step_t_2 t0 counter x y;
        
            loopFlag = true;
            step_t = app.HighEditField.Value;  % Time for High state
            step_t_2 = app.LowEditField.Value; % Time for Low state
        
            % Initialize timer if not already done
            
            t0 = tic;
            x = [];
            y = [];
            counter = 0;
            
        
            if strcmp(app.StartfromDropDown.Value, 'High')
                % Start from High state
                while loopFlag
                    % High state

                    if isempty(a) || ~isvalid(a)
                        disp('Arduino is not connected. Please click "Connect" first.');
                        return;
                    end

                    writeDigitalPin(a, 'D3', 1);
                    writeDigitalPin(a, 'D4', 1);
                    app.StateindicatorButton.Text = 'High';
                    app.StateindicatorButton.BackgroundColor = [0.635 0.078 0.184];
                    
                    % Update real-time monitor
                    updatePlot(app, 1); % High state = 1
                    pause(step_t);
                    
                    % Low state

                    if isempty(a) || ~isvalid(a)
                        disp('Arduino is not connected. Please click "Connect" first.');
                        return;
                    end

                    writeDigitalPin(a, 'D3', 0);
                    writeDigitalPin(a, 'D4', 0);
                    app.StateindicatorButton.Text = 'Low';
                    app.StateindicatorButton.BackgroundColor = [0 0.447 0.741];
                    
                    % Update real-time monitor
                    updatePlot(app, 0); % Low state = 0
                    pause(step_t_2);
                end
            else
                % Start from Low state
                while loopFlag
                    % Low state
                    writeDigitalPin(a, 'D3', 0);
                    writeDigitalPin(a, 'D4', 0);
                    app.StateindicatorButton.Text = 'Low';
                    app.StateindicatorButton.BackgroundColor = [0 0.447 0.741];
                    
                    % Update real-time monitor
                    updatePlot(app, 0); % Low state = 0
                    pause(step_t_2);
                    
                    % High state
                    writeDigitalPin(a, 'D3', 1);
                    writeDigitalPin(a, 'D4', 1);
                    app.StateindicatorButton.Text = 'High';
                    app.StateindicatorButton.BackgroundColor = [0.635 0.078 0.184];
                    
                    % Update real-time monitor
                    updatePlot(app, 1); % High state = 1
                    pause(step_t);
                end
    end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global loopFlag;
            
            loopFlag = false;
            app.StateindicatorButton.Text = 'State indicator';
            app.StateindicatorButton.BackgroundColor = [0.149 0.149 0.149];
        end

        % Value changed function: HighEditField
        function HighEditFieldValueChanged(app, event)
            global step_t
            value = app.HighEditField.Value;
            
            step_t = value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global a peri_pump;
            

                try
                    % Attempt to create the Arduino object
                    a = arduino('COM3', 'Uno');
                    disp('Arduino connected successfully.');
                catch ME
                    % Display an error if the connection fails
                    disp('Error connecting to Arduino:');
                    disp(ME.message);
                    a = []; % Ensure 'a' is empty if connection fails
                end

%             a = serial('COM7');
%             fopen(a)
            
%             peri_pump = serial('COM7');
%             set(peri_pump,'BaudRate',9600,'DataBits',8,'StopBits',1,'Parity','Even','Timeout',10);
%             fopen(peri_pump);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global a peri_pump;
            
%             fclose(peri_pump);

            try
                    if ~isempty(a) && isvalid(a)
                        clear a; % Clear the Arduino object
                        disp('Arduino disconnected successfully.');
                    else
                        disp('Arduino was not connected.');
                    end
                catch ME
                    disp('Error during disconnection:');
                    disp(ME.message);
                end
            
                clc;
                        
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            global loopFlag counter t0 x y;
            
            loopFlag = false;
            counter = 0;
            t0 = [];
            x = [];
            y = [];
        end

        % Value changed function: LowEditField
        function LowEditFieldValueChanged(app, event)
            global step_t_2
            value = app.LowEditField.Value;
            
            step_t_2 = value;
        end

        % Callback function
        function StartButton_2Pushed(app, event)
            global peri_pump;
            
            request = hex2dec(['01'; '06'; '03'; 'F0'; '00'; '01'; '48'; '7D']);
            fwrite(peri_pump, request);
        end

        % Callback function
        function StopButton_3Pushed(app, event)
            global peri_pump;
            
            request = hex2dec(['01'; '06'; '03'; 'F0'; '00'; '00'; '89'; 'BD']);
            fwrite(peri_pump, request);
        end

        % Callback function
        function FlowratemLminEditFieldValueChanged(app, event)
            global peri_pump;
            
            value = app.FlowratemLminEditField.Value;
            
            Q = num2hex(single(value));
            Q1 = Q(1:2);
            Q2 = Q(3:4);
            Q3 = Q(5:6);
            Q4 = Q(7:8);

            request = int16(hex2dec(['01'; '10'; '03'; 'EC'; '00'; '02'; '04'; '42'; '48'; '00'; '00'; '7D'; '2C']));
            fwrite(peri_pump, request);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-time monitor')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Nutrient level')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.ManualoperationPanel = uipanel(app.UIFigure);
            app.ManualoperationPanel.Title = 'Manual operation';
            app.HighButton = uibutton(app.ManualoperationPanel, 'push');
            app.HighButton.ButtonPushedFcn = createCallbackFcn(app, @HighButtonPushed, true);
            app.HighButton.Text = 'High';
            app.LowButton = uibutton(app.ManualoperationPanel, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @LowButtonPushed, true);
            app.LowButton.Text = 'Low';
            app.StopButton_2 = uibutton(app.ManualoperationPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.AutomatedoperationPanel = uipanel(app.UIFigure);
            app.AutomatedoperationPanel.Title = 'Automated operation';
            app.StartButton = uibutton(app.AutomatedoperationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AutomatedoperationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartfromDropDownLabel = uilabel(app.AutomatedoperationPanel);
            app.StartfromDropDownLabel.Text = 'Start from';
            app.StartfromDropDown = uidropdown(app.AutomatedoperationPanel);
            app.StartfromDropDown.Items = {'High', 'Low'};
            app.StartfromDropDown.Value = 'High';
            app.StateindicatorButton = uibutton(app.AutomatedoperationPanel, 'state');
            app.StateindicatorButton.Text = 'State indicator';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TimestepsecPanel = uipanel(app.UIFigure);
            app.TimestepsecPanel.Title = 'Time step (sec)';
            app.HighEditFieldLabel = uilabel(app.TimestepsecPanel);
            app.HighEditFieldLabel.Text = 'High';
            app.HighEditField = uieditfield(app.TimestepsecPanel, 'numeric');
            app.HighEditField.ValueChangedFcn = createCallbackFcn(app, @HighEditFieldValueChanged, true);
            app.HighEditField.Value = 5;
            app.LowEditFieldLabel = uilabel(app.TimestepsecPanel);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowEditField = uieditfield(app.TimestepsecPanel, 'numeric');
            app.LowEditField.ValueChangedFcn = createCallbackFcn(app, @LowEditFieldValueChanged, true);
            app.LowEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Juanita_w_pump_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end